classdef databaseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SAN9DatabaseUIFigure  matlab.ui.Figure
        UITable               matlab.ui.control.Table
        Panel                 matlab.ui.container.Panel
        LabelName             matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        Panel_2               matlab.ui.container.Panel
        UIAxes2               matlab.ui.control.UIAxes
        DropDownX             matlab.ui.control.DropDown
        DropDownY             matlab.ui.control.DropDown
        XYLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        databaseTable   % 存储数据的表
        numSelect       % 当前选择的行号
        xName           % X的名称
        yName           % Y的名称
    end
    
    methods (Access = private)
        function updateName(app)
            app.LabelName.Text = app.databaseTable.('姓名')(app.numSelect);
        end
        
        function updateXYLabel(app)
            app.XYLabel.Text = [app.xName,' - ',app.yName,' 关联图'];
        end
        
        function updatePlot(app)
            x = categorical(app.databaseTable.Properties.VariableNames(8:11)); % 能力名称
            y = table2array(app.databaseTable(app.numSelect,8:11)); % 能力数据
            b = bar(app.UIAxes,x,y); % 绘制柱形图
            b.FaceColor = 'flat'; % 面颜色模式
            cmap = colormap(app.UIAxes,lines); % 取颜色图
            b.CData = cmap(1:4,:); % 设置颜色
            b.LineStyle = 'none'; % 设置轮廓线形
            b.FaceAlpha = 0.8; % 设置透明度
        end
        
        function updatePlot2(app)
            x = app.databaseTable.(app.xName); % 提出x数据
            y = app.databaseTable.(app.yName); % 提出y数据
            pointSize = ones(size(x)); % 预分配"点尺寸"向量
            for i =1:size(x,1)
                temp = ismember([x y],[x(i) y(i)]); % 相同坐标提取
                numPoint = sum(all(temp')); % 相同坐标点的个数
                pointSize(i) = 500*sqrt(numPoint); % 此处的标记尺寸
            end
            % 绘制散点图
            s = scatter(app.UIAxes2,x,y);
            s.SizeData = pointSize;
            s.Marker = '.';
            s.MarkerEdgeAlpha  = 0.5;
            hold(app.UIAxes2,"on")
            app.UIAxes2.XLimMode = 'auto';
            app.UIAxes2.YLimMode = 'auto';
            app.UIAxes2.XLim = app.UIAxes2.XLim + app.UIAxes2.XLim(2)*[-0.05 0.05]; % X留5%边缘
            app.UIAxes2.YLim = app.UIAxes2.YLim + app.UIAxes2.YLim(2)*[-0.05 0.05]; % Y留5%边缘
            % 绘制拟合曲线图
            p = polyfit(x,y,2);
            f = fplot(app.UIAxes2,poly2sym(p));
            f.LineWidth = 2;
            hold(app.UIAxes2,"off")
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; % 清空命令行区
            app.databaseTable = readtable('database.xls','PreserveVariableNames',true); % 读取数据库
            app.databaseTable.('性格') = categorical(app.databaseTable.('性格'),{'冷静','慎重','刚胆','莽撞'}); 
            app.UITable.Data = app.databaseTable; % 建立UI表
            app.UITable.ColumnName = app.databaseTable.Properties.VariableNames; % 修改UI表头
            w1=58; w2=38; % 临时定义表列宽度
            app.UITable.ColumnWidth = {45 70 45 50 70 w1 w1 w1 w1 w1 w1 w1 w2 w2 w2 w2 w2 w2 w2 w2 w2 'auto'}; 
            app.UITable.ColumnEditable = [false(1,4) true false(1,17)]; % 控制"性格"列为可编辑
            app.UITable.ColumnSortable = true(1,22);  % 控制所有列为可排序
            %% 初始化下拉列表
            app.DropDownX.Items = app.databaseTable.Properties.VariableNames(6:12);
            app.xName = app.databaseTable.Properties.VariableNames{6};
            app.DropDownY.Items = app.databaseTable.Properties.VariableNames(6:22);
            app.yName = app.databaseTable.Properties.VariableNames{6};
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.numSelect = event.Indices(1); % 提取选择行号
            updateName(app); % 更新人物姓名
            updatePlot(app); % 更新人物能力图
        end

        % Value changed function: DropDownX
        function DropDownXValueChanged(app, event)
            app.xName = app.DropDownX.Value; % 读取下拉列表值
            updateXYLabel(app); % 更新关联图标签
            updatePlot2(app); % 更新关联图
        end

        % Value changed function: DropDownY
        function DropDownYValueChanged(app, event)
            app.yName = app.DropDownY.Value; % 读取下拉列表值
            updateXYLabel(app); % 更新关联图标签
            updatePlot2(app); % 更新关联图
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SAN9DatabaseUIFigure = uifigure('Visible', 'off');
            app.SAN9DatabaseUIFigure.Name = 'SAN9Database';
            app.UITable = uitable(app.SAN9DatabaseUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Panel = uipanel(app.SAN9DatabaseUIFigure);
            app.Panel.Title = '人物能力';
            app.LabelName = uilabel(app.Panel);
            app.LabelName.Text = '姓名';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.YLim = [0 100];
            app.Panel_2 = uipanel(app.SAN9DatabaseUIFigure);
            app.Panel_2.Title = '能力关联';
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.DropDownX = uidropdown(app.Panel_2);
            app.DropDownX.ValueChangedFcn = createCallbackFcn(app, @DropDownXValueChanged, true);
            app.DropDownY = uidropdown(app.Panel_2);
            app.DropDownY.ValueChangedFcn = createCallbackFcn(app, @DropDownYValueChanged, true);
            app.XYLabel = uilabel(app.Panel_2);
            app.XYLabel.Text = '“X”-“Y”关联图';
            % Show the figure after all components are created
            app.SAN9DatabaseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = databaseApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SAN9DatabaseUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SAN9DatabaseUIFigure)
        end
    end
end